classdef voicemod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        AudioSourceLabel     matlab.ui.control.Label
        UploadWavFileButton  matlab.ui.control.Button
        RecordWavFileButton  matlab.ui.control.Button
        RecordingLampLabel   matlab.ui.control.Label
        RecordingLamp        matlab.ui.control.Lamp
        CenterPanel          matlab.ui.container.Panel
        PlayAudioButton      matlab.ui.control.Button
        VolumeGaugeLabel     matlab.ui.control.Label
        VolumeGauge          matlab.ui.control.LinearGauge
        WaveformLabel        matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        RightPanel           matlab.ui.container.Panel
        PitchSliderLabel     matlab.ui.control.Label
        PitchSlider          matlab.ui.control.Slider
        RobotSliderLabel     matlab.ui.control.Label
        RobotSlider          matlab.ui.control.Slider
        DemonSliderLabel     matlab.ui.control.Label
        DemonSlider          matlab.ui.control.Slider
        VoiceControlsLabel   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        inputfile= 'inputwav.wav' % Description
        outputfile= 'outputwav.wav'
    end
    
    methods (Access = public)


    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadWavFileButton
        function UploadWavFileButtonPushed(app, event)
            filename= uigetfile;
            [DAFx_out, FS]= audioread(filename);
            audiowrite('inputwav.wav', DAFx_out, FS);
            %play the clip
            clip= audioplayer(DAFx_out,FS);
            clear sound;
            playblocking(clip);
            %display the file
            DAFx_out = DAFx_out(:,1);
            dt = 1/FS;
            ax=app.UIAxes_2;
            t = 0:dt:(length(y)*dt)-dt;
            plot(app, app.UIAxes_2, t, DAFx_out);
        end

        % Value changed function: DemonSlider
        function DemonSliderValueChanged(app, event)
            value = app.DemonSlider.Value;
            [DAFx_out, FS]= whisper_function('inputwav.wav', value);
            clip= audioplayer(DAFx_out,FS);
            audiowrite('outputwav.wav', DAFx_out, FS);
            clear sound;
            playblocking(clip);
        end

        % Value changed function: RobotSlider
        function RobotSliderValueChanged(app, event)
            value = app.RobotSlider.Value;
            [DAFx_out, FS]= robot_function('inputwav.wav', value);
            clip= audioplayer(DAFx_out,FS);
            audiowrite('outputwav.wav', DAFx_out, FS);
            clear sound;
            playblocking(clip);
        end

        % Value changed function: PitchSlider
        function PitchSliderValueChanged(app, event)
            value = app.PitchSlider.Value;
            [DAFx_out, FS]= pitch_function('inputwav.wav', value);
            clip= audioplayer(DAFx_out,FS);
            audiowrite('outputwav.wav', DAFx_out, FS);
            clear sound;
            playblocking(clip);
        end

        % Button pushed function: RecordWavFileButton
        function RecordWavFileButtonPushed(app, event)
            disp('button pressed');
            app.RecordingLamp.Visible='on';
            recObj = audiorecorder(8000, 16, 2);
            recordblocking(recObj, 5);
            app.RecordingLamp.Visible='off';
            myrec= getaudiodata(recObj);
            audiowrite('inputwav.wav', myrec, 8000);
            clip= audioplayer(myrec, 8000);
            clear sound;
            playblocking(clip);
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            %play the modified audio file
            disp('playing audio file')
            [DAFx_out, FS]= audioread('outputwav.wav');
            clip= audioplayer(DAFx_out, FS);
            clear sound;
            playblocking(clip);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {237, '1x', 225};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AudioSourceLabel = uilabel(app.LeftPanel);
            app.AudioSourceLabel.Text = 'Audio Source';
            app.UploadWavFileButton = uibutton(app.LeftPanel, 'push');
            app.UploadWavFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadWavFileButtonPushed, true);
            app.UploadWavFileButton.Text = 'Upload Wav File';
            app.RecordWavFileButton = uibutton(app.LeftPanel, 'push');
            app.RecordWavFileButton.ButtonPushedFcn = createCallbackFcn(app, @RecordWavFileButtonPushed, true);
            app.RecordWavFileButton.Text = 'Record Wav File';
            app.RecordingLampLabel = uilabel(app.LeftPanel);
            app.RecordingLampLabel.Visible = 'off';
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.LeftPanel);
            app.RecordingLamp.Visible = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.PlayAudioButton = uibutton(app.CenterPanel, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.VolumeGaugeLabel = uilabel(app.CenterPanel);
            app.VolumeGaugeLabel.Text = 'Volume';
            app.VolumeGauge = uigauge(app.CenterPanel, 'linear');
            app.WaveformLabel = uilabel(app.CenterPanel);
            app.WaveformLabel.Text = 'Waveform';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'resulting wav')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, 'original wav')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.PitchSliderLabel = uilabel(app.RightPanel);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.RightPanel);
            app.PitchSlider.Limits = [0 3];
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @PitchSliderValueChanged, true);
            app.RobotSliderLabel = uilabel(app.RightPanel);
            app.RobotSliderLabel.Text = 'Robot';
            app.RobotSlider = uislider(app.RightPanel);
            app.RobotSlider.Limits = [0 1];
            app.RobotSlider.ValueChangedFcn = createCallbackFcn(app, @RobotSliderValueChanged, true);
            app.DemonSliderLabel = uilabel(app.RightPanel);
            app.DemonSliderLabel.Text = 'Demon';
            app.DemonSlider = uislider(app.RightPanel);
            app.DemonSlider.Limits = [0 2];
            app.DemonSlider.ValueChangedFcn = createCallbackFcn(app, @DemonSliderValueChanged, true);
            app.VoiceControlsLabel = uilabel(app.RightPanel);
            app.VoiceControlsLabel.Text = 'Voice Controls';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voicemod

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end